classdef MakeIntanSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FindButton                 matlab.ui.control.Button
        ConfigDirEditField         matlab.ui.control.EditField
        ConfigdirectoryLabel       matlab.ui.control.Label
        DigCh3Switch               matlab.ui.control.Switch
        DigCh3SwitchLabel          matlab.ui.control.Label
        DigCh1Switch               matlab.ui.control.Switch
        DigCh1SwitchLabel          matlab.ui.control.Label
        DigCh2Switch               matlab.ui.control.Switch
        DigCh2SwitchLabel          matlab.ui.control.Label
        DigCh0Switch               matlab.ui.control.Switch
        DigCh0SwitchLabel          matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        ColumnsDropDown            matlab.ui.control.DropDown
        ColumnsDropDownLabel       matlab.ui.control.Label
        SRateDropDown              matlab.ui.control.DropDown
        SamplingrateDropDownLabel  matlab.ui.control.Label
        PortBDropDown              matlab.ui.control.DropDown
        PortBDropDownLabel         matlab.ui.control.Label
        PortADropDown              matlab.ui.control.DropDown
        PortADropDownLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            %set base directory
            seldir=uigetdir;
            if seldir~=0
                app.ConfigDirEditField.Value=seldir;
            end
            figure(app.UIFigure)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %determine number of channels
            if contains(app.PortADropDown.Value,'64')
                nPortA=64;
            elseif contains(app.PortADropDown.Value,'128')
                nPortA=128;
            elseif strcmp(app.PortADropDown.Value,'Tetrode')
                nPortA=4;
            else 
                nPortA=1;
            end

            if contains(app.PortBDropDown.Value,'64')
                nPortB=64;
            elseif contains(app.PortBDropDown.Value,'128')
                nPortB=128;
            elseif strcmp(app.PortBDropDown.Value,'Tetrode')
                nPortB=4;
            elseif strcmp(app.PortBDropDown.Value,'Single')
                nPortB=1;
            else
                nPortB=0;
            end


            %load probe files
            if nPortA==64 || nPortA==128
                probefile=fullfile(app.ConfigDirEditField.Value,'microprobe_wiring',['probe_' app.PortADropDown.Value]);
                run(probefile); close;
                %get channel number, x, z, and organize as function of
                %depth
                %more complicated sort to keep shafts separate
                tmp=probewiring(probewiring(:,5)==1,[1 2 4]); %organization: channel id, x, <>, z, shaft
                [~,idx] = sort(tmp(:,3),'descend'); %sort by z
                probeA = tmp(idx,:);
                
                tmp = probewiring(probewiring(:,5)==2,[1 2 4]);
                [~,idx] = sort(tmp(:,3),'descend');
                probeA = [probeA; tmp(idx,:)];

            end
            if nPortB==64 || nPortB==128
                probefile=fullfile(app.ConfigDirEditField.Value,'microprobe_wiring',['probe_' app.PortBDropDown.Value]);
                run(probefile); close;
                tmp=probewiring(probewiring(:,5)==1,[1 2 4]); %organization: channel id, x, <>, z, shaft
                [~,idx] = sort(tmp(:,3),'descend'); %sort by z
                probeB = tmp(idx,:);
                
                tmp = probewiring(probewiring(:,5)==2,[1 2 4]);
                [~,idx] = sort(tmp(:,3),'descend');
                probeB = [probeB; tmp(idx,:)];
            end

            %% settings file 
            %load correct base configuration file            
            if nPortA==64 && nPortB==0
                if strcmp(app.ColumnsDropDown.Value,'ALow_AHigh')
                    fname=fullfile(app.ConfigDirEditField.Value,'IntanBaseSettings','Config64Ch_ALow_AHigh.xml');
                    XMLInput=readstruct(fname);
                end
            end

            %update fields as necessary
            %sample rate
            XMLInput.SampleRateHertzAttribute=app.SRateDropDown.Value;

            %organization of analog channels - most superficial on top
            idxPortA=find(strcmp([XMLInput.SignalGroup.PrefixAttribute],'A'));
            for i=0:nPortA-1
                %top channel is entry 1 in probeA, and UserOrder 0 in the
                %xml file
                chIdx=find(probeA(:,1)==i+1);
                XMLInput.SignalGroup(idxPortA).Channel(i+1).UserOrderAttribute=num2str(chIdx-1);
            end


            %activate digital channels - all are disabled by default
            idxDigIn=find(strcmp([XMLInput.SignalGroup.PrefixAttribute],'DIGITAL-IN'));
            for i=0:3
                if strcmp(app.(['DigCh' num2str(i) 'Switch']).Value,'On')
                    XMLInput.SignalGroup(idxDigIn).Channel(i+1).EnabledAttribute='True';
                else
                    XMLInput.SignalGroup(idxDigIn).Channel(i+1).EnabledAttribute='False';
                end
            end

            %save settings file
            [file,path]=uiputfile('*.xml','Save settings file');
            if file~=0
                writestruct(XMLInput,fullfile(path,file),'StructNodeName','IntanRHX');
            end

            %% probe map file
            ProbeMap=struct;
            ProbeMap.versionAttribute= "3.0.0";
            ProbeMap.typeAttribute="ControllerStimRecordUSB2";
            if strcmp(app.SRateDropDown.Value,'30000')
                ProbeMap.sampleRateAttribute= "30 kHz";
            elseif strcmp(app.SRateDropDown.Value,'20000')
                ProbeMap.sampleRateAttribute= "20 kHz";
            end

            ProbeMap.ProbeMapSettings.backgroundColorAttribute='Black';

            ProbeMap.ProbeMapSettings.Page(1).nameAttribute=app.PortADropDown.Value;
            ProbeMap.ProbeMapSettings.Page(1).Port.nameAttribute='A';
            ProbeMap.ProbeMapSettings.Page(1).Port.siteShapeAttribute='Ellipse';
            ProbeMap.ProbeMapSettings.Page(1).Port.siteWidthAttribute=20;
            ProbeMap.ProbeMapSettings.Page(1).Port.siteHeightAttribute=20;

            for i=1:nPortA
                ProbeMap.ProbeMapSettings.Page(1).Port.ElectrodeSite(i).channelNumberAttribute=probeA(i,1);
                ProbeMap.ProbeMapSettings.Page(1).Port.ElectrodeSite(i).xAttribute=probeA(i,2);
                ProbeMap.ProbeMapSettings.Page(1).Port.ElectrodeSite(i).yAttribute=probeA(i,3);
            end

            %save settings file
            [file,path]=uiputfile('*.xml','Save probe map');
            if file~=0
                writestruct(ProbeMap,fullfile(path,file),'StructNodeName','IntanRHX');                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PortADropDownLabel = uilabel(app.UIFigure);
            app.PortADropDownLabel.Text = 'Port A';
            app.PortADropDown = uidropdown(app.UIFigure);
            app.PortADropDown.Items = {'64D', '64F', '64ML', 'Tetrode', 'Single'};
            app.PortADropDown.Value = '64D';
            app.PortBDropDownLabel = uilabel(app.UIFigure);
            app.PortBDropDownLabel.Text = 'Port B';
            app.PortBDropDown = uidropdown(app.UIFigure);
            app.PortBDropDown.Items = {'none', '64D', '64F', '64ML', 'Tetrode', 'Single'};
            app.PortBDropDown.Value = 'none';
            app.SamplingrateDropDownLabel = uilabel(app.UIFigure);
            app.SamplingrateDropDownLabel.Text = 'Sampling rate';
            app.SRateDropDown = uidropdown(app.UIFigure);
            app.SRateDropDown.Items = {'30000', '20000'};
            app.SRateDropDown.Value = '30000';
            app.ColumnsDropDownLabel = uilabel(app.UIFigure);
            app.ColumnsDropDownLabel.Text = 'Columns';
            app.ColumnsDropDown = uidropdown(app.UIFigure);
            app.ColumnsDropDown.Items = {'L: Port A Low, R: Port A High'};
            app.ColumnsDropDown.ItemsData = {'ALow_AHigh'};
            app.ColumnsDropDown.Value = 'ALow_AHigh';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.DigCh0SwitchLabel = uilabel(app.UIFigure);
            app.DigCh0SwitchLabel.Text = 'Dig Ch 0';
            app.DigCh0Switch = uiswitch(app.UIFigure, 'slider');
            app.DigCh0Switch.Value = 'On';
            app.DigCh2SwitchLabel = uilabel(app.UIFigure);
            app.DigCh2SwitchLabel.Text = 'Dig Ch 2';
            app.DigCh2Switch = uiswitch(app.UIFigure, 'slider');
            app.DigCh2Switch.Value = 'On';
            app.DigCh1SwitchLabel = uilabel(app.UIFigure);
            app.DigCh1SwitchLabel.Text = 'Dig Ch 1';
            app.DigCh1Switch = uiswitch(app.UIFigure, 'slider');
            app.DigCh1Switch.Value = 'On';
            app.DigCh3SwitchLabel = uilabel(app.UIFigure);
            app.DigCh3SwitchLabel.Text = 'Dig Ch 3';
            app.DigCh3Switch = uiswitch(app.UIFigure, 'slider');
            app.DigCh3Switch.Value = 'On';
            app.ConfigdirectoryLabel = uilabel(app.UIFigure);
            app.ConfigdirectoryLabel.Text = 'Config directory';
            app.ConfigDirEditField = uieditfield(app.UIFigure, 'text');
            app.ConfigDirEditField.Value = 'C:\Kristina\git local\stimulator_master\COM_acquisition\COM_Intan';
            app.FindButton = uibutton(app.UIFigure, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MakeIntanSettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end