classdef GaGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ModuleDropDown            matlab.ui.control.DropDown
        ModuleDropDownLabel       matlab.ui.control.Label
        ExperimentField           matlab.ui.control.EditField
        ExperimentEditFieldLabel  matlab.ui.control.Label
        UnitField                 matlab.ui.control.EditField
        UnitEditFieldLabel        matlab.ui.control.Label
        AnimalField               matlab.ui.control.EditField
        AnimalEditFieldLabel      matlab.ui.control.Label
        GenerationField           matlab.ui.control.NumericEditField
        GenerationEditFieldLabel  matlab.ui.control.Label
        MakeStimuliButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mList=moduleListMaster('G'); %get correct module list
            for i=1:length(mList)
                modStrings{i} = mList{i}{1};
            end
            app.ModuleDropDown.Items=modStrings;
            app.DModule.ItemsData=[1:length(mList)];
        end

        % Button pushed function: MakeStimuliButton
        function MakeStimuliButtonPushed(app, event)
            mList=moduleListMaster('G');
            gaScript=mList{app.DModule.Value}{2};

            doneFlag=feval(gaScript,app.AnimalField.Value,app.UnitField.Value,app.ExperimentField.Value,app.GenerationField.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MakeStimuliButton = uibutton(app.UIFigure, 'push');
            app.MakeStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @MakeStimuliButtonPushed, true);
            app.MakeStimuliButton.Text = 'Make stimuli';
            app.GenerationEditFieldLabel = uilabel(app.UIFigure);
            app.GenerationEditFieldLabel.Text = 'Generation';
            app.GenerationField = uieditfield(app.UIFigure, 'numeric');
            app.GenerationField.Limits = [1 Inf];
            app.GenerationField.Value = 1;
            app.AnimalEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalEditFieldLabel.Text = {'Animal'; ''};
            app.AnimalField = uieditfield(app.UIFigure, 'text');
            app.UnitEditFieldLabel = uilabel(app.UIFigure);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.UnitField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentField = uieditfield(app.UIFigure, 'text');
            app.ModuleDropDownLabel = uilabel(app.UIFigure);
            app.ModuleDropDownLabel.Text = 'Module';
            app.ModuleDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end