classdef LooperNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LooperUIFigure           matlab.ui.Figure
        SRand                    matlab.ui.control.CheckBox
        SBlanks                  matlab.ui.control.CheckBox
        perrepeatEditFieldLabel  matlab.ui.control.Label
        ENBlanks                 matlab.ui.control.NumericEditField
        ESymbol1                 matlab.ui.control.EditField
        ESymbol2                 matlab.ui.control.EditField
        ESymbol3                 matlab.ui.control.EditField
        ESymbol4                 matlab.ui.control.EditField
        ESymbol5                 matlab.ui.control.EditField
        EValue1                  matlab.ui.control.EditField
        EValue2                  matlab.ui.control.EditField
        EValue3                  matlab.ui.control.EditField
        EValue4                  matlab.ui.control.EditField
        EValue5                  matlab.ui.control.EditField
        SBlock1                  matlab.ui.control.CheckBox
        SBlock2                  matlab.ui.control.CheckBox
        SBlock3                  matlab.ui.control.CheckBox
        SBlock4                  matlab.ui.control.CheckBox
        SBlock5                  matlab.ui.control.CheckBox
        TTrial1                  matlab.ui.control.Label
        TTrial2                  matlab.ui.control.Label
        TTrial3                  matlab.ui.control.Label
        TTrial4                  matlab.ui.control.Label
        TTrial5                  matlab.ui.control.Label
        SymbolLabel              matlab.ui.control.Label
        ValuevectorLabel         matlab.ui.control.Label
        BlockLabel               matlab.ui.control.Label
        FormulaTextAreaLabel     matlab.ui.control.Label
        EFormula                 matlab.ui.control.TextArea
        BLoad                    matlab.ui.control.Button
        BSave                    matlab.ui.control.Button
        CurrenttrialLabel        matlab.ui.control.Label
        NrrepeatsLabel           matlab.ui.control.Label
        ENReps                   matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function  blockOk=checkBlocks(app)
            blockOk=1;
            bout=[];
            
            for i=1:5
                stext=eval(['app.ESymbol' num2str(i) '.Value;']);
                if ~isempty(stext)
                    bval=eval(['app.SBlock' num2str(i) '.Value;']);
                    bout=[bout;bval];
                end
            end
            
            if ~isempty(bout) && all(bout)
                uialert(app.LooperUIFigure,'Not all parameters can be blocked! Resetting.','Error');
                blockOk=0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BLoad
        function BLoadButtonPushed(app, event)
            global Lstate setupDefault
            
            [file, path] = uigetfile(fullfile(setupDefault.loopParamRoot,'*.loop'),'Load looper state');
            
            if file  %if 'cancel' was not pressed
                %load looper file
                ffile = fullfile(path,file);
                load(ffile,'-mat','Lstate')
                if length(Lstate.param{1})<3 %backwards compatibility after adding blocking option
                    for i=1:length(Lstate.param)
                        Lstate.param{i}{3}=0;
                    end
                end
                
                %clear all of the entries before adding new ones
                for i=1:5
                    eval(['app.ESymbol' num2str(i) '.Value="";']);
                    eval(['app.EValue' num2str(i) '.Value="";']);
                    eval(['app.SBlock' num2str(i) '.Value=0;']);
                    eval(['app.TTrial' num2str(i) '.Text="";']);
                end
                
                %now set new values
                app.ENReps.Value=Lstate.reps;
                app.SRand.Value=Lstate.rand;
                app.SBlanks.Value=Lstate.blanks;
                if Lstate.blanks==1
                    app.ENBlanks.Value=Lstate.blankperiod;
                else
                    app.ENBlanks.Value=0;
                end
                
                for i=1:length(Lstate.param)
                    eval(['app.ESymbol' num2str(i) '.Value=Lstate.param{i}{1};']);
                    eval(['app.EValue' num2str(i) '.Value=Lstate.param{i}{2};']);
                    eval(['app.SBlock' num2str(i) '.Value=Lstate.param{i}{3};']);
                end
                
                %add formula; reformat to keep on individual lines if
                %necessary
                if size(Lstate.formula,1)==1
                    %disp(Lstate.formula)
                    tmpForm=strsplit(Lstate.formula{1},';'); %split into separate statements
                    tmpForm=strtrim(tmpForm); %remove blanks
                    %disp(tmpForm)
                    app.EFormula.Value=tmpForm;
                else
                    app.EFormula.Value=cellstr(Lstate.formula);
                end
                    
                %done
                disp(['File loaded: ' file]);
            end


        end

        % Button pushed function: BSave
        function BSaveButtonPushed(app, event)
            global Lstate setupDefault
            
            [file, path] = uiputfile(fullfile(setupDefault.loopParamRoot,'*.loop'),'Save as');
            
            if file  %if 'cancel' was not pressed
                ffile = fullfile(path,file);
                updateLstate  %Make sure that stuff in GUI goes to Lstate
                save(ffile,'Lstate')
            end
        end

        % Value changed function: SBlock1
        function SBlock1ValueChanged(app, event)
            %we will use one callback for all block selections
            %needs to simply make sure that at least on parameter is not
            %selected as blocked
            blockOk=checkBlocks(app);
            if blockOk==0
                app.SBlock1.Value=0;
            end
        end

        % Value changed function: SBlock2
        function SBlock2ValueChanged(app, event)
            blockOk=checkBlocks(app);
            if blockOk==0
                app.SBlock2.Value=0;
            end
        end

        % Value changed function: SBlock3
        function SBlock3ValueChanged(app, event)
           blockOk=checkBlocks(app);
            if blockOk==0
                app.SBlock3.Value=0;
            end
        end

        % Value changed function: SBlock4
        function SBlock4ValueChanged(app, event)
           blockOk=checkBlocks(app);
            if blockOk==0
                app.SBlock4.Value=0;
            end           
        end

        % Value changed function: SBlock5
        function SBlock5ValueChanged(app, event)
            blockOk=checkBlocks(app);
            if blockOk==0
                app.SBlock5.Value=0;
            end           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LooperUIFigure = uifigure('Visible', 'off');
            app.LooperUIFigure.Name = 'Looper';
            app.SRand = uicheckbox(app.LooperUIFigure);
            app.SRand.Text = 'Randomize';
            app.SRand.Value = true;
            app.SBlanks = uicheckbox(app.LooperUIFigure);
            app.SBlanks.Text = 'Blanks';
            app.SBlanks.Value = true;
            app.perrepeatEditFieldLabel = uilabel(app.LooperUIFigure);
            app.perrepeatEditFieldLabel.Text = 'per repeat';
            app.ENBlanks = uieditfield(app.LooperUIFigure, 'numeric');
            app.ENBlanks.Value = 1;
            app.ESymbol1 = uieditfield(app.LooperUIFigure, 'text');
            app.ESymbol2 = uieditfield(app.LooperUIFigure, 'text');
            app.ESymbol3 = uieditfield(app.LooperUIFigure, 'text');
            app.ESymbol4 = uieditfield(app.LooperUIFigure, 'text');
            app.ESymbol5 = uieditfield(app.LooperUIFigure, 'text');
            app.EValue1 = uieditfield(app.LooperUIFigure, 'text');
            app.EValue2 = uieditfield(app.LooperUIFigure, 'text');
            app.EValue3 = uieditfield(app.LooperUIFigure, 'text');
            app.EValue4 = uieditfield(app.LooperUIFigure, 'text');
            app.EValue5 = uieditfield(app.LooperUIFigure, 'text');
            app.SBlock1 = uicheckbox(app.LooperUIFigure);
            app.SBlock1.ValueChangedFcn = createCallbackFcn(app, @SBlock1ValueChanged, true);
            app.SBlock1.Text = '';
            app.SBlock2 = uicheckbox(app.LooperUIFigure);
            app.SBlock2.ValueChangedFcn = createCallbackFcn(app, @SBlock2ValueChanged, true);
            app.SBlock2.Text = '';
            app.SBlock3 = uicheckbox(app.LooperUIFigure);
            app.SBlock3.ValueChangedFcn = createCallbackFcn(app, @SBlock3ValueChanged, true);
            app.SBlock3.Text = '';
            app.SBlock4 = uicheckbox(app.LooperUIFigure);
            app.SBlock4.ValueChangedFcn = createCallbackFcn(app, @SBlock4ValueChanged, true);
            app.SBlock4.Text = '';
            app.SBlock5 = uicheckbox(app.LooperUIFigure);
            app.SBlock5.ValueChangedFcn = createCallbackFcn(app, @SBlock5ValueChanged, true);
            app.SBlock5.Text = '';
            app.TTrial1 = uilabel(app.LooperUIFigure);
            app.TTrial1.Text = '';
            app.TTrial2 = uilabel(app.LooperUIFigure);
            app.TTrial2.Text = '';
            app.TTrial3 = uilabel(app.LooperUIFigure);
            app.TTrial3.Text = '';
            app.TTrial4 = uilabel(app.LooperUIFigure);
            app.TTrial4.Text = '';
            app.TTrial5 = uilabel(app.LooperUIFigure);
            app.TTrial5.Text = '';
            app.SymbolLabel = uilabel(app.LooperUIFigure);
            app.SymbolLabel.Text = 'Symbol';
            app.ValuevectorLabel = uilabel(app.LooperUIFigure);
            app.ValuevectorLabel.Text = 'Value vector';
            app.BlockLabel = uilabel(app.LooperUIFigure);
            app.BlockLabel.Text = 'Block?';
            app.FormulaTextAreaLabel = uilabel(app.LooperUIFigure);
            app.FormulaTextAreaLabel.Text = 'Formula';
            app.EFormula = uitextarea(app.LooperUIFigure);
            app.BLoad = uibutton(app.LooperUIFigure, 'push');
            app.BLoad.ButtonPushedFcn = createCallbackFcn(app, @BLoadButtonPushed, true);
            app.BLoad.Text = 'Load';
            app.BSave = uibutton(app.LooperUIFigure, 'push');
            app.BSave.ButtonPushedFcn = createCallbackFcn(app, @BSaveButtonPushed, true);
            app.BSave.Text = 'Save';
            app.CurrenttrialLabel = uilabel(app.LooperUIFigure);
            app.CurrenttrialLabel.Text = 'Current trial';
            app.NrrepeatsLabel = uilabel(app.LooperUIFigure);
            app.NrrepeatsLabel.Text = 'Nr repeats';
            app.ENReps = uieditfield(app.LooperUIFigure, 'numeric');
            app.ENReps.Value = 1;
            % Show the figure after all components are created
            app.LooperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LooperNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LooperUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LooperUIFigure)
        end
    end
end