classdef manualMapperNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManualMapperUIFigure  matlab.ui.Figure
        BPlay                 matlab.ui.control.Button
        DStimulus             matlab.ui.control.DropDown
        StimulusLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mList=moduleListMaster('M');
            
            for i=1:length(mList)
                modStrings{i} = mList{i}{2};
            end
            app.DStimulus.Items=modStrings;
            app.DStimulus.ItemsData=[1:length(mList)];
            
        end

        % Button pushed function: BPlay
        function BPlayButtonPushed(app, event)
            global  Mstate stereoFlag
            
            %update Mstate
            Mstate.running = 0;
            updateMstate
            
            %get correct module
            if stereoFlag==0
                mList=moduleListMaster('M');
            else
                mList=moduleListStereo('M');
            end
            modId=mList{app.DStimulus.Value}{1};
                        
            %only send monitor parameters to display
            sendMinfo;
            waitforDisplayResp;
            
            %start
            startManual(modId);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManualMapperUIFigure = uifigure('Visible', 'off');
            app.ManualMapperUIFigure.Name = 'Manual Mapper';
            app.StimulusLabel = uilabel(app.ManualMapperUIFigure);
            app.StimulusLabel.Text = {'Stimulus'; ''};
            app.DStimulus = uidropdown(app.ManualMapperUIFigure);
            app.DStimulus.Items = {};
            app.DStimulus.Editable = 'on';
            app.DStimulus.Value = {};
            app.BPlay = uibutton(app.ManualMapperUIFigure, 'push');
            app.BPlay.ButtonPushedFcn = createCallbackFcn(app, @BPlayButtonPushed, true);
            app.BPlay.Text = 'Play';
            % Show the figure after all components are created
            app.ManualMapperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualMapperNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManualMapperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManualMapperUIFigure)
        end
    end
end