classdef shutterSettingNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ChannelconfPanel          matlab.ui.container.ButtonGroup
        SConf1                    matlab.ui.control.RadioButton
        SConf2                    matlab.ui.control.RadioButton
        OpenbitsPanel             matlab.ui.container.Panel
        LEDropDownLabel           matlab.ui.control.Label
        DLE                       matlab.ui.control.DropDown
        REDropDownLabel           matlab.ui.control.Label
        DRE                       matlab.ui.control.DropDown
        WaittimemsEditFieldLabel  matlab.ui.control.Label
        EWaitTime                 matlab.ui.control.EditField
        SetpositionPanel          matlab.ui.container.Panel
        BLEopen                   matlab.ui.control.StateButton
        BREopen                   matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global shutterInfo
            
            
            if shutterInfo.LEch==1
                app.SConf1.Value=1; %no need to deselect the other one
            else
                app.SConf2.Value=1;
            end
            
            if shutterInfo.LEopen==1
                app.DLE.Value='1';
            else
                app.DLE.Value='0';
            end
            if shutterInfo.REopen==1
                app.DRE.Value='1';
            else
                app.DRE.Value='0';
            end
            
            app.EWaitTime.Value=num2str(shutterInfo.waitTime);
            
        end

        % Selection changed function: ChannelconfPanel
        function ChannelconfPanelSelectionChanged(app, event)
            global shutterInfo
            
            if app.SConf1.Value==1
                shutterInfo.LEch=1;
                shutterInfo.REch=2;
            else
                shutterInfo.LEch=2;
                shutterInfo.REch=1;
            end
            
            
        end

        % Value changed function: DLE
        function DLEValueChanged(app, event)
            global shutterInfo
            
            shutterInfo.LEopen=str2num(app.DLE.Value);
            
        end

        % Value changed function: DRE
        function DREValueChanged(app, event)
            global shutterInfo
            
            shutterInfo.REopen=str2num(app.DRE.Value);
            
        end

        % Value changed function: EWaitTime
        function EWaitTimeValueChanged(app, event)
            global shutterInfo
            
            shutterInfo.waitTime=str2num(app.EWaitTime.Value);
        end

        % Value changed function: BLEopen
        function BLEopenValueChanged(app, event)
            global shutterInfo
            
            if app.BLEopen.Value==1 %open shutter
                LEbit = 1;
                if shutterInfo.LEopen==0
                    LEbit=1-LEbit;
                end
                               
                moveShutter(shutterInfo.LEch,LEbit);
                waitforDisplayResp
                app.BLEopen.Text='Close';
            else %close shutter
                LEbit = 0;
                if shutterInfo.LEopen==0
                    LEbit=1-LEbit;
                end
                               
                moveShutter(shutterInfo.LEch,LEbit);
                waitforDisplayResp
                app.BLEopen.Text='Open';
            end     
           
        end

        % Value changed function: BREopen
        function BREopenValueChanged(app, event)
            global shutterInfo
            
            if app.BREopen.Value==1 %open shutter
                REbit = 1;
                if shutterInfo.REopen==0
                    REbit=1-REbit;
                end
                               
                moveShutter(shutterInfo.REch,REbit);
                waitforDisplayResp
                app.BREopen.Text='Close';
            else %close
                REbit = 0;
                if shutterInfo.REopen==0
                    REbit=1-REbit;
                end
                               
                moveShutter(shutterInfo.REch,REbit);
                waitforDisplayResp
                app.BREopen.Text='Open';
            end     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChannelconfPanel = uibuttongroup(app.UIFigure);
            app.ChannelconfPanel.SelectionChangedFcn = createCallbackFcn(app, @ChannelconfPanelSelectionChanged, true);
            app.ChannelconfPanel.Title = 'Channel assignment';
            app.SConf1 = uiradiobutton(app.ChannelconfPanel);
            app.SConf1.Text = 'Ch1 LE, Ch2 RE';
            app.SConf1.Value = true;
            app.SConf2 = uiradiobutton(app.ChannelconfPanel);
            app.SConf2.Text = 'Ch1 RE, Ch2 LE';
            app.OpenbitsPanel = uipanel(app.UIFigure);
            app.OpenbitsPanel.Title = 'Open bits';
            app.LEDropDownLabel = uilabel(app.OpenbitsPanel);
            app.LEDropDownLabel.Text = 'LE';
            app.DLE = uidropdown(app.OpenbitsPanel);
            app.DLE.Items = {'1', '0'};
            app.DLE.ValueChangedFcn = createCallbackFcn(app, @DLEValueChanged, true);
            app.DLE.Value = '1';
            app.REDropDownLabel = uilabel(app.OpenbitsPanel);
            app.REDropDownLabel.Text = 'RE';
            app.DRE = uidropdown(app.OpenbitsPanel);
            app.DRE.Items = {'1', '0'};
            app.DRE.ValueChangedFcn = createCallbackFcn(app, @DREValueChanged, true);
            app.DRE.Value = '1';
            app.WaittimemsEditFieldLabel = uilabel(app.UIFigure);
            app.WaittimemsEditFieldLabel.Text = 'Wait time (ms)';
            app.EWaitTime = uieditfield(app.UIFigure, 'text');
            app.EWaitTime.ValueChangedFcn = createCallbackFcn(app, @EWaitTimeValueChanged, true);
            app.EWaitTime.Value = '100';
            app.SetpositionPanel = uipanel(app.UIFigure);
            app.SetpositionPanel.Title = 'Set position';
            app.BLEopen = uibutton(app.SetpositionPanel, 'state');
            app.BLEopen.ValueChangedFcn = createCallbackFcn(app, @BLEopenValueChanged, true);
            app.BLEopen.Text = 'LE open';
            app.BREopen = uibutton(app.SetpositionPanel, 'state');
            app.BREopen.ValueChangedFcn = createCallbackFcn(app, @BREopenValueChanged, true);
            app.BREopen.Text = 'RE open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shutterSettingNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end